classdef main_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationeconomyUIFigure      matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        EconomyTab                     matlab.ui.container.Tab
        util_sigma                     matlab.ui.control.NumericEditField
        kLabel_2                       matlab.ui.control.Label
        NewfirmemergenceprobEditField  matlab.ui.control.NumericEditField
        NewfirmemergenceprobEditFieldLabel  matlab.ui.control.Label
        ProductiontechnologyEditField  matlab.ui.control.NumericEditField
        ProductiontechnologyEditFieldLabel  matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        NumberofsimulationsEditField   matlab.ui.control.NumericEditField
        NumberofsimulationsLabel       matlab.ui.control.Label
        BondpriceLabel                 matlab.ui.control.Label
        CostoflivingLabel              matlab.ui.control.Label
        UtilityfunctionLabel           matlab.ui.control.Label
        CostOfLiving                   matlab.ui.control.NumericEditField
        ClEditFieldLabel               matlab.ui.control.Label
        util_rho                       matlab.ui.control.NumericEditField
        kLabel                         matlab.ui.control.Label
        ProductionfunctionLabel        matlab.ui.control.Label
        AdditionalparametersLabel      matlab.ui.control.Label
        MainparametersLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Q                              matlab.ui.control.NumericEditField
        QEditFieldLabel                matlab.ui.control.Label
        TechnologygrowthrateEditField  matlab.ui.control.NumericEditField
        TechnologygrowthrateEditFieldLabel  matlab.ui.control.Label
        NumberofhouseholdsEditField    matlab.ui.control.NumericEditField
        NumberofhouseholdsLabel        matlab.ui.control.Label
        NumberoffirmsEditField         matlab.ui.control.NumericEditField
        NumberoffirmsLabel             matlab.ui.control.Label
        QlearningTab                   matlab.ui.container.Tab
        QlearningalgorithmtypeButtonGroup  matlab.ui.container.ButtonGroup
        DeltaadjustmentButton          matlab.ui.control.RadioButton
        LearningrateadjustmentButton   matlab.ui.control.RadioButton
        MovingdiscretizationpointsButton  matlab.ui.control.RadioButton
        DiscountrateLabel              matlab.ui.control.Label
        betaEditField                  matlab.ui.control.NumericEditField
        betaEditFieldLabel             matlab.ui.control.Label
        LearningRate                   matlab.ui.control.NumericEditField
        LearningrateEditFieldLabel     matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        ProbabilityofselectingrandomactionLabel  matlab.ui.control.Label
        epsilonEditField_2             matlab.ui.control.NumericEditField
        Probabilityofselectingrandomactionepsilon_fLabel  matlab.ui.control.Label
        epsilon_wEditField             matlab.ui.control.NumericEditField
        Probabilityoffirmselectingrandomactionepsilon_fEditField_2Label  matlab.ui.control.Label
        ApplyButton                    matlab.ui.control.Button
        SimulationsettingsLabel        matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        PrimaryfiguresTab              matlab.ui.container.Tab
        Labor                          matlab.ui.control.UIAxes
        Price                          matlab.ui.control.UIAxes
        Unemploynment                  matlab.ui.control.UIAxes
        Consumption                    matlab.ui.control.UIAxes
        MoneySupply                    matlab.ui.control.UIAxes
        SecondaryfiguresTab            matlab.ui.container.Tab
        ReservedforfutureuseLabel      matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        ExportresultsButton            matlab.ui.control.Button
        RoundstEditField               matlab.ui.control.NumericEditField
        RoundstEditFieldLabel          matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        RunButton                      matlab.ui.control.StateButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        sims
        cfg
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, economy, hist, indx)
            app.cfg = setup.config();
            app.cfg = setup.grids(app.cfg);
            app.sims = sim.empty(app.cfg.NumberOfSimulations,0);
            
            for sim_indx = 1:app.cfg.NumberOfSimulations
                app.sims(sim_indx) = sim;
                app.sims(sim_indx).reset(app.cfg);
            end
            
            set_gui_setting(app)
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            app.NumberoffirmsEditField.Editable = 0;
            app.NumberofhouseholdsEditField.Editable = 0;
            app.NumberofsimulationsEditField.Editable = 0;
            app.ResetButton.Enable = 0;
            app.ApplyButton.Enable = 0;
           
            app.RunButton.Enable = 0;
            app.RunButton.Text = 'Running...';
            pause(0.001);
            run_sims_and_plot(app, app.RoundstEditField.Value);
            
            app.ResetButton.Enable = 1;
            app.ApplyButton.Enable = 1;
            app.RunButton.Enable = 1;
            app.RunButton.Text = 'Run';
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            nf=app.cfg.n_firms;
            nh=app.cfg.n_households;
            ns=app.cfg.NumberOfSimulations;
            get_setting(app);
        
            if not(nf == app.cfg.n_firms && nh==app.cfg.n_households && ns==app.cfg.NumberOfSimulations)
                for sim_indx = 1:app.cfg.NumberOfSimulations
                    app.sims(sim_indx) = sim;
                    app.sims(sim_indx).reset(app.cfg);
                end
            end
        end

        % Button pushed function: ResetButton
        function Reset(app, event)
            
            app.cfg = setup.config();
            app.cfg = setup.grids(app.cfg);
            app.sims = sim.empty(app.cfg.NumberOfSimulations,0);
            
            for sim_indx = 1:app.cfg.NumberOfSimulations
                app.sims(sim_indx) = sim;
                app.sims(sim_indx).reset(app.cfg);
            end
            

            set_gui_setting(app);
            
            app.NumberoffirmsEditField.Editable = 1;
            app.NumberofhouseholdsEditField.Editable = 1;
            
            cla(app.Labor);
            cla(app.Price);
            cla(app.Unemploynment);
            cla(app.Consumption);
            cla(app.MoneySupply);
            cla(app.Technology);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SimulationeconomyUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {785, 785, 785};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {785, 785};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x', 179};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationeconomyUIFigure = uifigure('Visible', 'off');
            app.SimulationeconomyUIFigure.Name = 'Simulation economy';
            app.SimulationeconomyUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SimulationeconomyUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SimulationsettingsLabel = uilabel(app.LeftPanel);
            app.SimulationsettingsLabel.Text = 'Simulation settings';
            app.ApplyButton = uibutton(app.LeftPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.EconomyTab = uitab(app.TabGroup);
            app.EconomyTab.Title = 'Economy';
            app.NumberoffirmsLabel = uilabel(app.EconomyTab);
            app.NumberoffirmsLabel.Text = 'Number of firms*';
            app.NumberoffirmsEditField = uieditfield(app.EconomyTab, 'numeric');
            app.NumberofhouseholdsLabel = uilabel(app.EconomyTab);
            app.NumberofhouseholdsLabel.Text = 'Number of households*';
            app.NumberofhouseholdsEditField = uieditfield(app.EconomyTab, 'numeric');
            app.TechnologygrowthrateEditFieldLabel = uilabel(app.EconomyTab);
            app.TechnologygrowthrateEditFieldLabel.Text = 'Technology growth rate';
            app.TechnologygrowthrateEditField = uieditfield(app.EconomyTab, 'numeric');
            app.QEditFieldLabel = uilabel(app.EconomyTab);
            app.QEditFieldLabel.Interpreter = 'latex';
            app.QEditFieldLabel.Text = '$Q$';
            app.Q = uieditfield(app.EconomyTab, 'numeric');
            app.Q.Limits = [0 1];
            app.Label_2 = uilabel(app.EconomyTab);
            app.Label_2.Text = '%';
            app.alphaEditFieldLabel = uilabel(app.EconomyTab);
            app.alphaEditFieldLabel.Interpreter = 'latex';
            app.alphaEditFieldLabel.Text = '$\alpha$';
            app.alphaEditField = uieditfield(app.EconomyTab, 'numeric');
            app.MainparametersLabel = uilabel(app.EconomyTab);
            app.MainparametersLabel.Text = 'Main parameters';
            app.AdditionalparametersLabel = uilabel(app.EconomyTab);
            app.AdditionalparametersLabel.Text = 'Additional parameters';
            app.ProductionfunctionLabel = uilabel(app.EconomyTab);
            app.ProductionfunctionLabel.Text = 'Production function';
            app.kLabel = uilabel(app.EconomyTab);
            app.kLabel.Interpreter = 'latex';
            app.kLabel.Text = '$\varphi$';
            app.util_rho = uieditfield(app.EconomyTab, 'numeric');
            app.ClEditFieldLabel = uilabel(app.EconomyTab);
            app.ClEditFieldLabel.Interpreter = 'latex';
            app.ClEditFieldLabel.Text = '$Cl$';
            app.CostOfLiving = uieditfield(app.EconomyTab, 'numeric');
            app.UtilityfunctionLabel = uilabel(app.EconomyTab);
            app.UtilityfunctionLabel.Text = 'Utility function';
            app.CostoflivingLabel = uilabel(app.EconomyTab);
            app.CostoflivingLabel.Text = 'Cost of living';
            app.BondpriceLabel = uilabel(app.EconomyTab);
            app.BondpriceLabel.Text = 'Bond price';
            app.NumberofsimulationsLabel = uilabel(app.EconomyTab);
            app.NumberofsimulationsLabel.Text = 'Number of simulations**';
            app.NumberofsimulationsEditField = uieditfield(app.EconomyTab, 'numeric');
            app.NumberofsimulationsEditField.Value = 1;
            app.Label_5 = uilabel(app.EconomyTab);
            app.Label_5.Text = '** Increasing the number of simulations produces nicer figures, but it has severe negative effects on performance.';
            app.Label_6 = uilabel(app.EconomyTab);
            app.Label_6.Text = '* Parameter can be changed on by resetting the simulation.';
            app.ProductiontechnologyEditFieldLabel = uilabel(app.EconomyTab);
            app.ProductiontechnologyEditFieldLabel.Text = 'Production technology';
            app.ProductiontechnologyEditField = uieditfield(app.EconomyTab, 'numeric');
            app.NewfirmemergenceprobEditFieldLabel = uilabel(app.EconomyTab);
            app.NewfirmemergenceprobEditFieldLabel.Text = 'New firm emergence prob.';
            app.NewfirmemergenceprobEditField = uieditfield(app.EconomyTab, 'numeric');
            app.kLabel_2 = uilabel(app.EconomyTab);
            app.kLabel_2.Interpreter = 'latex';
            app.kLabel_2.Text = '$\sigma$';
            app.util_sigma = uieditfield(app.EconomyTab, 'numeric');
            app.QlearningTab = uitab(app.TabGroup);
            app.QlearningTab.Title = 'Q-learning';
            app.Probabilityoffirmselectingrandomactionepsilon_fEditField_2Label = uilabel(app.QlearningTab);
            app.Probabilityoffirmselectingrandomactionepsilon_fEditField_2Label.Interpreter = 'latex';
            app.Probabilityoffirmselectingrandomactionepsilon_fEditField_2Label.Text = '$\epsilon_w$';
            app.epsilon_wEditField = uieditfield(app.QlearningTab, 'numeric');
            app.Probabilityofselectingrandomactionepsilon_fLabel = uilabel(app.QlearningTab);
            app.Probabilityofselectingrandomactionepsilon_fLabel.Interpreter = 'latex';
            app.Probabilityofselectingrandomactionepsilon_fLabel.Text = '$\epsilon$';
            app.epsilonEditField_2 = uieditfield(app.QlearningTab, 'numeric');
            app.ProbabilityofselectingrandomactionLabel = uilabel(app.QlearningTab);
            app.ProbabilityofselectingrandomactionLabel.Text = 'Probability of selecting random action';
            app.Label_3 = uilabel(app.QlearningTab);
            app.Label_3.Text = '%';
            app.Label_4 = uilabel(app.QlearningTab);
            app.Label_4.Text = '%';
            app.LearningrateEditFieldLabel = uilabel(app.QlearningTab);
            app.LearningrateEditFieldLabel.Text = 'Learning rate';
            app.LearningRate = uieditfield(app.QlearningTab, 'numeric');
            app.betaEditFieldLabel = uilabel(app.QlearningTab);
            app.betaEditFieldLabel.Interpreter = 'latex';
            app.betaEditFieldLabel.Text = '$\beta$';
            app.betaEditField = uieditfield(app.QlearningTab, 'numeric');
            app.DiscountrateLabel = uilabel(app.QlearningTab);
            app.DiscountrateLabel.Text = 'Discount rate';
            app.QlearningalgorithmtypeButtonGroup = uibuttongroup(app.QlearningTab);
            app.QlearningalgorithmtypeButtonGroup.Title = 'Q-learning algorithm type';
            app.MovingdiscretizationpointsButton = uiradiobutton(app.QlearningalgorithmtypeButtonGroup);
            app.MovingdiscretizationpointsButton.Text = 'Moving discretization points';
            app.MovingdiscretizationpointsButton.Value = true;
            app.LearningrateadjustmentButton = uiradiobutton(app.QlearningalgorithmtypeButtonGroup);
            app.LearningrateadjustmentButton.Text = 'Learning rate adjustment';
            app.DeltaadjustmentButton = uiradiobutton(app.QlearningalgorithmtypeButtonGroup);
            app.DeltaadjustmentButton.Text = 'Delta adjustment';
            app.CenterPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.CenterPanel);
            app.PrimaryfiguresTab = uitab(app.TabGroup2);
            app.PrimaryfiguresTab.Title = 'Primary figures';
            app.MoneySupply = uiaxes(app.PrimaryfiguresTab);
            title(app.MoneySupply, 'Money supply')
            xlabel(app.MoneySupply, 't')
            ylabel(app.MoneySupply, 'Aggregate money supply')
            zlabel(app.MoneySupply, 'Z')
            app.Consumption = uiaxes(app.PrimaryfiguresTab);
            title(app.Consumption, 'Consumption')
            xlabel(app.Consumption, 't')
            ylabel(app.Consumption, 'Goods consumed')
            zlabel(app.Consumption, 'Z')
            app.Unemploynment = uiaxes(app.PrimaryfiguresTab);
            title(app.Unemploynment, 'Unemploynment rate')
            xlabel(app.Unemploynment, 't')
            ylabel(app.Unemploynment, 'Unemploynment rate [%]')
            zlabel(app.Unemploynment, 'Z')
            app.Price = uiaxes(app.PrimaryfiguresTab);
            title(app.Price, 'Price level')
            xlabel(app.Price, 't')
            ylabel(app.Price, 'Goods price / wage')
            zlabel(app.Price, 'Z')
            app.Labor = uiaxes(app.PrimaryfiguresTab);
            title(app.Labor, 'Labor participation rate')
            xlabel(app.Labor, 't')
            ylabel(app.Labor, 'Labor participation [%]')
            zlabel(app.Labor, 'Z')
            app.SecondaryfiguresTab = uitab(app.TabGroup2);
            app.SecondaryfiguresTab.Title = 'Secondary figures';
            app.ReservedforfutureuseLabel = uilabel(app.SecondaryfiguresTab);
            app.ReservedforfutureuseLabel.Text = 'Reserved for future use';
            app.RightPanel = uipanel(app.GridLayout);
            app.RunButton = uibutton(app.RightPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetButton.Text = 'Reset';
            app.RoundstEditFieldLabel = uilabel(app.RightPanel);
            app.RoundstEditFieldLabel.Text = 'Rounds (t)';
            app.RoundstEditField = uieditfield(app.RightPanel, 'numeric');
            app.RoundstEditField.Limits = [1 Inf];
            app.RoundstEditField.Value = 2500;
            app.ExportresultsButton = uibutton(app.RightPanel, 'push');
            app.ExportresultsButton.Enable = 'off';
            app.ExportresultsButton.Text = 'Export results';
            % Show the figure after all components are created
            app.SimulationeconomyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationeconomyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationeconomyUIFigure)
        end
    end
end